classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGEmbeddedWaveformExtractor10alphaUIFigure  matlab.ui.Figure
        MainGrid                   matlab.ui.container.GridLayout
        GridLayout5                matlab.ui.container.GridLayout
        FileEditField              matlab.ui.control.EditField
        FileEditFieldLabel         matlab.ui.control.Label
        OpenButton                 matlab.ui.control.Button
        SignalPanel                matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout3                matlab.ui.container.GridLayout
        SignalAxes_2               matlab.ui.control.UIAxes
        SignalAxes                 matlab.ui.control.UIAxes
        FilterAxes                 matlab.ui.control.UIAxes
        ExtraPanel                 matlab.ui.container.GridLayout
        StatsPanel                 matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        UITable                    matlab.ui.control.Table
        ControlPanel               matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        ExtractedSignalsButton     matlab.ui.control.Button
        FFTOriginalFilteredButton  matlab.ui.control.Button
        OriginalFilteredButton     matlab.ui.control.Button
        CompareLabel               matlab.ui.control.Label
        PlotFilteredFFTButton      matlab.ui.control.Button
        PlotFilteredButton         matlab.ui.control.Button
        ExtractionLabel            matlab.ui.control.Label
        DomainLabel                matlab.ui.control.Label
        DeltaButton                matlab.ui.control.Button
        ThetaButton                matlab.ui.control.Button
        AlphaButton                matlab.ui.control.Button
        GammaButton                matlab.ui.control.Button
        PlotFFTButton              matlab.ui.control.Button
        PlotOriginalButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        file_time
        left_eeg
        right_eeg
        left_time
        right_time
    end
    
    methods (Access = private)
        
        function clearFilter(app)

            % Clear the filter axes
            cla(app.FilterAxes);
            delete(app.FilterAxes);
            app.FilterAxes = uiaxes(app.GridLayout2);
            xlabel(app.FilterAxes, 'X');
            ylabel(app.FilterAxes, 'Y');
            grid(app.FilterAxes, "on");

        end

        function plotFilter(app, Hd, fs, n)

            clearFilter(app);

            % Compute the frequency response
            freq = linspace(0, fs/2, n);
            [H, f] = freqz(Hd, freq, fs);

            % Convert freq response to phase response in radians
            phase = unwrap(angle(H));
            
            % Find the maximum value of the phase response
            max_phase = max(phase);
            
            % Align the phase response by subtracting the maximum phase value
            aligned_phase = phase - max_phase;
            
            % Plot magnitude response in dB
            p1 = plot(app.FilterAxes, f, 20*log10(abs(H)), 'b');
            xlabel(app.FilterAxes, 'Frequency (Hz)');
            ylabel(app.FilterAxes, 'Magnitude Response (dB)');
            title(app.FilterAxes, 'Magnitude/Phase Response');
            
            % Set the y-axis limits for the magnitude plot
            ylim(app.FilterAxes, [-100, 0]);

            % Plot phase response in radians
            yyaxis(app.FilterAxes, "right")
            p2 = plot(app.FilterAxes, f, aligned_phase, '-r');
            ylabel(app.FilterAxes, "Phase Response (radians)");
            
            % Set the y-axis limits for the phase plot
            ylim(app.FilterAxes, [min(aligned_phase), 0]);
            
            % Return to default axis and set other options
            yyaxis(app.FilterAxes, "left");
            grid(app.FilterAxes, "on");
            legend(app.FilterAxes, [p1, p2],  {"Magnitude", "Phase"});

            % Create a listener for changes to the axis limits
            addlistener(app.FilterAxes, 'YLim', 'PostSet', @(src, event) updateAxisLimits(app));
            
            function updateAxisLimits(app)
                % Get the y-axis limits of the left yyaxis
                yLimits = ylim(app.FilterAxes);
            
                % Set the y-axis limits for the right yyaxis
                yyaxis(app.FilterAxes, "right");
                ylim(app.FilterAxes, yLimits);
                yyaxis(app.FilterAxes, "left");
            end

        end

        function cleanData = getTableData(app, Fstop1, Fstop2, Fpass1, Fpass2, Astop1, Apass, Astop2)

            cleanData = [];
        
            if Fstop1 ~= -1
                cleanData = [cleanData; {'Fstop1', Fstop1}];
            end
        
            if Fstop2 ~= -1
                cleanData = [cleanData; {'Fstop2', Fstop2}];
            end
        
            if Fpass1 ~= -1
                cleanData = [cleanData; {'Fpass1', Fpass1}];
            end
        
            if Fpass2 ~= -1
                cleanData = [cleanData; {'Fpass2', Fpass2}];
            end
        
            if Astop1 ~= -1
                cleanData = [cleanData; {'Astop1', Astop1}];
            end
        
            if Apass ~= -1
                cleanData = [cleanData; {'Apass', Apass}];
            end
        
            if Astop2 ~= -1
                cleanData = [cleanData; {'Astop2', Astop2}];
            end
        
            return
        
        end


        function printStats(app, signal_left, signal_right, isFiltered, isFFT, Fstop1, Fstop2, Fpass1, Fpass2, Astop1, Apass, Astop2)

            % Clear the table data
            app.UITable.Data = [];

            if isFiltered

                data = getTableData(app, Fstop1, Fstop2, Fpass1, Fpass2, Astop1, Apass, Astop2);

                app.UITable.Data = data;

            end

            if ~isFFT
    
                % For left signal =========================================

                % shared variables
                x1 = abs(signal_left);
    
                % Find the signal energy
                e1 = sum(abs(x1).^2);
    
                % Find the signal power
                y1 = length(x1);
                p1 = (1/y1)*e1;
    
                % Find the signal max
                maxValue1 = max(signal_left);
    
                % Find the signal min
                minValue1 = min(signal_left);
    
                % Find the signal mean
                meanValue1 = mean(signal_left);
    
                % Find the signal standard deviation
                deviation1 = std(signal_left);
    
                % For right signal ========================================

                % shared variables
                x2 = abs(signal_right);
                
                % Find the signal energy
                e2 = sum(abs(x2).^2);
                
                % Find the signal power
                y2 = length(x2);
                p2 = (1/y2)*e2;
                
                % Find the signal max
                maxValue2 = max(signal_right);
                
                % Find the signal min
                minValue2 = min(signal_right);
                
                % Find the signal mean
                meanValue2 = mean(signal_right);
                
                % Find the signal standard deviation
                deviation2 = std(signal_right);
                
                % Apply on the table ======================================

                app.UITable.Data = [app.UITable.Data; {'Left Signal Energy', e1}; {'Left Signal Power', p1}; {'Left Signal Max', maxValue1}; {'Left Signal Min', minValue1}; {'Left Signal Mean', meanValue1}; {'Left Signal Standard Deviation', deviation1};];                  
                app.UITable.Data = [app.UITable.Data; {'Right Signal Energy', e2}; {'Right Signal Power', p2}; {'Right Signal Max', maxValue2}; {'Right Signal Min', minValue2}; {'Right Signal Mean', meanValue2}; {'Right Signal Standard Deviation', deviation2};];

            end

        end

        function filtered_signal = getFilteredSignal(app, signal, plotGraph)

            % Find sampling frequency
            n = length(signal);
            fs = n / max(app.file_time);

            % TODO: replace with automatic detection
            % Filter parameters
            Fpass1 = 48.5;        % First Passband Frequency
            Fstop1 = 49;          % First Stopband Frequency
            Fstop2 = 51;          % Second Stopband Frequency
            Fpass2 = 51.5;        % Second Passband Frequency
            Apass1 = 1;           % First Passband Ripple (dB)
            Astop  = 60;          % Stopband Attenuation (dB)
            Apass2 = 1;           % Second Passband Ripple (dB)
            match  = 'stopband';  % Band to match exactly
            
            % Construct an FDESIGN object and call its CHEBY2 method.
            h  = fdesign.bandstop(Fpass1, Fstop1, Fstop2, Fpass2, Apass1, Astop, Apass2, fs);
            Hd = design(h, 'cheby2', 'MatchExactly', match);
            
            filtered_signal = filter(Hd, signal);

            if plotGraph
                plotFilter(app, Hd, fs, n);
            end

            return

        end

        function plotSignal(app)

            clearFilter(app);

            % Plot the left signal
            plot(app.SignalAxes, app.left_time, app.left_eeg);
            xlabel(app.SignalAxes, 'Time');
            ylabel(app.SignalAxes, 'Voltage (uV)');
            title(app.SignalAxes, 'EEG Signal Left');
            xlim(app.SignalAxes, [0, max(app.left_time)]);
            ylim(app.SignalAxes,'auto');

            % Plot the right signal
            plot(app.SignalAxes_2, app.right_time, app.right_eeg);
            xlabel(app.SignalAxes_2, 'Time');
            ylabel(app.SignalAxes_2, 'Voltage (uV)');
            title(app.SignalAxes_2, 'EEG Signal Right');
            xlim(app.SignalAxes_2, [0, max(app.right_time)]);
            ylim(app.SignalAxes_2,'auto');

            printStats(app, app.left_eeg, app.right_eeg, false, false, -1, -1, -1, -1, -1, -1, -1);

        end

        function plotFilteredSignal(app) 

            % Get filtered left signal
            filtered_right = getFilteredSignal(app, app.left_eeg, false);

            % Get filtered right signal
            filtered_left = getFilteredSignal(app, app.right_eeg, true);

            % Plot the filtered left signal
            plot(app.SignalAxes, app.left_time, filtered_left);
            xlabel(app.SignalAxes, 'Time');
            ylabel(app.SignalAxes, 'Voltage (uV)');
            title(app.SignalAxes, 'Filtered EEG Signal Left');
            xlim(app.SignalAxes, [0, max(app.left_time)]);
            ylim(app.SignalAxes,'auto');

            % Plot the right signal
            plot(app.SignalAxes_2, app.right_time, filtered_right);
            xlabel(app.SignalAxes_2, 'Time');
            ylabel(app.SignalAxes_2, 'Voltage (uV)');
            title(app.SignalAxes_2, 'Filtered EEG Signal Right');
            xlim(app.SignalAxes_2, [0, max(app.right_time)]);
            ylim(app.SignalAxes_2,'auto');


            % TODO: replace with automatic detection
            % Filter parameters
            Fpass1 = 48.5;        % First Passband Frequency
            Fstop1 = 49;          % First Stopband Frequency
            Fstop2 = 51;          % Second Stopband Frequency
            Fpass2 = 51.5;        % Second Passband Frequency
            Apass1 = 1;           % First Passband Ripple (dB)
            Astop  = 60;          % Stopband Attenuation (dB)
            Apass2 = 1;           % Second Passband Ripple (dB)
            printStats(app, filtered_left, filtered_right, true, false, Fstop1, Fstop2, Fpass1, Fpass2, Astop, Apass1, -1);

        end

        function plotFFT(app)

            clearFilter(app);

            % Find the left eeg FFt
            left_n = length(app.left_eeg);
            left_fs = left_n / max(app.file_time);
            left_frequencies = (0:left_n-1) * (left_fs/left_n);
            left_fft = abs(fft(app.left_eeg, left_n));

            % Find the right eeg FFt
            right_n = length(app.right_eeg);
            right_fs = right_n / max(app.file_time);
            right_frequencies = (0:right_n-1) * (right_fs/right_n);
            right_fft = abs(fft(app.right_eeg, right_n));

            % Plot the left signal fft
            plot(app.SignalAxes, left_frequencies, left_fft);
            xlabel(app.SignalAxes, 'Frequency');
            ylabel(app.SignalAxes, '|x|');
            title(app.SignalAxes, 'EEG Signal Left FFT');
            xlim(app.SignalAxes, 'auto');
            ylim(app.SignalAxes,'auto');

            % Plot the right signal fft
            plot(app.SignalAxes_2, right_frequencies, right_fft);
            xlabel(app.SignalAxes_2, 'Frequency');
            ylabel(app.SignalAxes_2, '|x|');
            title(app.SignalAxes_2, 'EEG Signal Right FFT');
            xlim(app.SignalAxes_2, 'auto');
            ylim(app.SignalAxes_2,'auto');

            printStats(app, left_fft, right_fft, false, true, -1, -1, -1, -1, -1, -1, -1);

        end

        function plotFilteredFFT(app)

            % Get filtered left signal
            filtered_left = getFilteredSignal(app, app.left_eeg, false);

            % Get filtered right signal
            filtered_right = getFilteredSignal(app, app.right_eeg, true);

            % Get the filtered left and right FFT signals
            filtered_left_fft = abs(fft(filtered_left));
            filtered_right_fft = abs(fft(filtered_right));

            % Find frequencies for left and right FFT signals
            left_n = length(filtered_left);
            left_fs = left_n / max(app.file_time);
            left_frequencies = (0:left_n-1) * (left_fs/left_n);

            right_n = length(filtered_right);
            right_fs = right_n / max(app.file_time);
            right_frequencies = (0:right_n-1) * (right_fs/right_n);

            % Plot the left signal fft
            plot(app.SignalAxes, left_frequencies, filtered_left_fft);
            xlabel(app.SignalAxes, 'Frequency');
            ylabel(app.SignalAxes, '|x|');
            title(app.SignalAxes, 'Filtered EEG Signal Left FFT');
            xlim(app.SignalAxes, 'auto');
            ylim(app.SignalAxes,'auto');

            % Plot the left signal fft
            plot(app.SignalAxes_2, right_frequencies, filtered_right_fft);
            xlabel(app.SignalAxes_2, 'Frequency');
            ylabel(app.SignalAxes_2, '|x|');
            title(app.SignalAxes_2, 'Filtered EEG Signal Right FFT');
            xlim(app.SignalAxes_2, 'auto');
            ylim(app.SignalAxes_2,'auto');

            % TODO: replace with automatic detection
            % Filter parameters
            Fpass1 = 48.5;        % First Passband Frequency
            Fstop1 = 49;          % First Stopband Frequency
            Fstop2 = 51;          % Second Stopband Frequency
            Fpass2 = 51.5;        % Second Passband Frequency
            Apass1 = 1;           % First Passband Ripple (dB)
            Astop  = 60;          % Stopband Attenuation (dB)
            Apass2 = 1;           % Second Passband Ripple (dB)
            printStats(app, filtered_left_fft, filtered_right_fft, true, true, Fstop1, Fstop2, Fpass1, Fpass2, Astop, Apass1, -1);

        end

        function plotGamma(app)

            % Get filtered left signal
            filtered_left = getFilteredSignal(app, app.left_eeg, false);

            % Get filtered right signal
            filtered_right = getFilteredSignal(app, app.right_eeg, true);

            % Find left and right sampling frequency
            left_n = length(filtered_left);
            left_fs = left_n / max(app.file_time);
            right_n = length(filtered_right);
            right_fs = right_n / max(app.file_time);

            Fstop = 31.5;        % Stopband Frequency
            Fpass = 32;          % Passband Frequency
            Astop = 80;          % Stopband Attenuation (dB)
            Apass = 1;           % Passband Ripple (dB)
            match = 'stopband';  % Band to match exactly
            
            % Construct an FDESIGN object and call its CHEBY2 method.
            h  = fdesign.highpass(Fstop, Fpass, Astop, Apass, ((left_fs + right_fs) / 2));
            Hd = design(h, 'cheby2', 'MatchExactly', match);
            
            left_gamma = filter(Hd, filtered_left);
            right_gamma = filter(Hd, filtered_right);

            % Plot the left gamma signal
            plot(app.SignalAxes, app.left_time, left_gamma);
            xlabel(app.SignalAxes, 'Time');
            ylabel(app.SignalAxes, 'Voltage (uV)');
            title(app.SignalAxes, 'Gamma Signal Left');
            xlim(app.SignalAxes, [0, 10]);
            ylim(app.SignalAxes,'auto');

            % Plot the left gamma signal
            plot(app.SignalAxes_2, app.right_time, right_gamma);
            xlabel(app.SignalAxes_2, 'Time');
            ylabel(app.SignalAxes_2, 'Voltage (uV)');
            title(app.SignalAxes_2, 'Gamma Signal Right');
            xlim(app.SignalAxes_2, [0, 10]);
            ylim(app.SignalAxes_2,'auto');

            % Plot the filter graph
            plotFilter(app, Hd, ((left_fs + right_fs) / 2), ((left_n + right_n) / 2));

            % Print signal stats
            printStats(app, left_gamma, right_gamma, true, false, Fstop, -1, Fpass, -1, Astop, Apass, -1);


        end

        function plotAlpha(app)

        
            % Get filtered left signal
            filtered_left = getFilteredSignal(app, app.left_eeg, false);

            % Get filtered right signal
            filtered_right = getFilteredSignal(app, app.right_eeg, true);

            % Find left and right sampling frequency
            left_n = length(filtered_left);
            left_fs = left_n / max(app.file_time);
            right_n = length(filtered_right);
            right_fs = right_n / max(app.file_time);

            Fstop1 = 7.5;         % First Stopband Frequency
            Fpass1 = 8;           % First Passband Frequency
            Fpass2 = 12;          % Second Passband Frequency
            Fstop2 = 12.5;        % Second Stopband Frequency
            Astop1 = 60;          % First Stopband Attenuation (dB)
            Apass  = 1;           % Passband Ripple (dB)
            Astop2 = 80;          % Second Stopband Attenuation (dB)
            match  = 'stopband';  % Band to match exactly
            
            % Construct an FDESIGN object and call its CHEBY2 method.
            h  = fdesign.bandpass(Fstop1, Fpass1, Fpass2, Fstop2, Astop1, Apass, Astop2, ((left_fs + right_fs) / 2));
            Hd = design(h, 'cheby2', 'MatchExactly', match);
            
            left_alpha = filter(Hd, filtered_left);
            right_alpha = filter(Hd, filtered_right);

            % Plot the left alpha signal
            plot(app.SignalAxes, app.left_time, left_alpha);
            xlabel(app.SignalAxes, 'Time');
            ylabel(app.SignalAxes, 'Voltage (uV)');
            title(app.SignalAxes, 'Alpha Signal Left');
            xlim(app.SignalAxes, [0, 10]);
            ylim(app.SignalAxes,'auto');

            % Plot the left alpha signal
            plot(app.SignalAxes_2, app.right_time, right_alpha);
            xlabel(app.SignalAxes_2, 'Time');
            ylabel(app.SignalAxes_2, 'Voltage (uV)');
            title(app.SignalAxes_2, 'Alpha Signal Right');
            xlim(app.SignalAxes_2, [0, 10]);
            ylim(app.SignalAxes_2,'auto');

            % Plot the filter graph
            plotFilter(app, Hd, ((left_fs + right_fs) / 2), ((left_n + right_n) / 2));

            % Print signal stats
            printStats(app, left_alpha, right_alpha, true, false, Fstop1, Fstop2, Fpass1, Fpass2, Astop1, Apass, Astop2);


        end

        function plotTheta(app)

        
            % Get filtered left signal
            filtered_left = getFilteredSignal(app, app.left_eeg, false);

            % Get filtered right signal
            filtered_right = getFilteredSignal(app, app.right_eeg, true);

            % Find left and right sampling frequency
            left_n = length(filtered_left);
            left_fs = left_n / max(app.file_time);
            right_n = length(filtered_right);
            right_fs = right_n / max(app.file_time);

            Fstop1 = 3.5;         % First Stopband Frequency
            Fpass1 = 4;           % First Passband Frequency
            Fpass2 = 7;           % Second Passband Frequency
            Fstop2 = 7.1;         % Second Stopband Frequency
            Astop1 = 60;          % First Stopband Attenuation (dB)
            Apass  = 1;           % Passband Ripple (dB)
            Astop2 = 80;          % Second Stopband Attenuation (dB)
            match  = 'stopband';  % Band to match exactly
            
            % Construct an FDESIGN object and call its CHEBY2 method.
            h  = fdesign.bandpass(Fstop1, Fpass1, Fpass2, Fstop2, Astop1, Apass, Astop2, ((left_fs + right_fs) / 2));
            Hd = design(h, 'cheby2', 'MatchExactly', match);
            
            left_theta = filter(Hd, filtered_left);
            right_theta = filter(Hd, filtered_right);

            % Plot the left alpha signal
            plot(app.SignalAxes, app.left_time, left_theta);
            xlabel(app.SignalAxes, 'Time');
            ylabel(app.SignalAxes, 'Voltage (uV)');
            title(app.SignalAxes, 'Theta Signal Left');
            xlim(app.SignalAxes, [0, 10]);
            ylim(app.SignalAxes,'auto');

            % Plot the left alpha signal
            plot(app.SignalAxes_2, app.right_time, right_theta);
            xlabel(app.SignalAxes_2, 'Time');
            ylabel(app.SignalAxes_2, 'Voltage (uV)');
            title(app.SignalAxes_2, 'Theta Signal Right');
            xlim(app.SignalAxes_2, [0, 10]);
            ylim(app.SignalAxes_2,'auto');

            % Plot the filter graph
            plotFilter(app, Hd, ((left_fs + right_fs) / 2), ((left_n + right_n) / 2));

            % Print signal stats
            printStats(app, left_theta, right_theta, true, false, Fstop1, Fstop2, Fpass1, Fpass2, Astop1, Apass, Astop2);


        end

        function plotDelta(app)

        
            % Get filtered left signal
            filtered_left = getFilteredSignal(app, app.left_eeg, false);

            % Get filtered right signal
            filtered_right = getFilteredSignal(app, app.right_eeg, true);

            % Find left and right sampling frequency
            left_n = length(filtered_left);
            left_fs = left_n / max(app.file_time);
            right_n = length(filtered_right);
            right_fs = right_n / max(app.file_time);

            Fstop1 = 0.01;        % First Stopband Frequency
            Fpass1 = 0.2;         % First Passband Frequency
            Fpass2 = 4;           % Second Passband Frequency
            Fstop2 = 4.1;         % Second Stopband Frequency
            Astop1 = 60;          % First Stopband Attenuation (dB)
            Apass  = 1;           % Passband Ripple (dB)
            Astop2 = 80;          % Second Stopband Attenuation (dB)
            match  = 'stopband';  % Band to match exactly
            
            % Construct an FDESIGN object and call its CHEBY2 method.
            h  = fdesign.bandpass(Fstop1, Fpass1, Fpass2, Fstop2, Astop1, Apass, Astop2, ((left_fs + right_fs) / 2));
            Hd = design(h, 'cheby2', 'MatchExactly', match);
            
            left_delta = filter(Hd, filtered_left);
            right_delta = filter(Hd, filtered_right);

            % Plot the left alpha signal
            plot(app.SignalAxes, app.left_time, left_delta);
            xlabel(app.SignalAxes, 'Time');
            ylabel(app.SignalAxes, 'Voltage (uV)');
            title(app.SignalAxes, 'Delta Signal Left');
            xlim(app.SignalAxes, [0, 10]);
            ylim(app.SignalAxes,'auto');

            % Plot the left alpha signal
            plot(app.SignalAxes_2, app.right_time, right_delta);
            xlabel(app.SignalAxes_2, 'Time');
            ylabel(app.SignalAxes_2, 'Voltage (uV)');
            title(app.SignalAxes_2, 'Delta Signal Right');
            xlim(app.SignalAxes_2, [0, 10]);
            ylim(app.SignalAxes_2,'auto');

            % Plot the filter graph
            plotFilter(app, Hd, ((left_fs + right_fs) / 2), ((left_n + right_n) / 2));

            % Print signal stats
            printStats(app, left_delta, right_delta, true, false, Fstop1, Fstop2, Fpass1, Fpass2, Astop1, Apass, Astop2);


        end

        function compareOriginal(app)

            % Create a popup figure
            figure('Name', 'Compare Original and Filtered EEG', 'Position', [100,100,1080,720]);

            % Plot the left signal
            subplot(4, 1, 1);
            plot(app.left_time, app.left_eeg, 'r');
            xlabel('Time');
            ylabel('Voltage (uV)');
            title('EEG Signal Left');
            xlim([0, max(app.left_time)]);
            ylim('auto');

            % Plot the right signal
            subplot(4, 1, 2)
            plot(app.right_time, app.right_eeg, 'r');
            xlabel('Time');
            ylabel('Voltage (uV)');
            title('EEG Signal Right');
            xlim([0, max(app.right_time)]);
            ylim('auto');

            % ============================================================

            % Get filtered left signal
            filtered_right = getFilteredSignal(app, app.left_eeg, false);

            % Get filtered right signal
            filtered_left = getFilteredSignal(app, app.right_eeg, false);

            % Plot the filtered left signal
            subplot(4, 1, 3)
            plot(app.left_time, filtered_left, 'r');
            xlabel('Time');
            ylabel('Voltage (uV)');
            title('Filtered EEG Signal Left');
            xlim([0, max(app.left_time)]);
            ylim('auto');

            % Plot the right signal
            subplot(4, 1, 4)
            plot(app.right_time, filtered_right, 'r');
            xlabel('Time');
            ylabel('Voltage (uV)');
            title('Filtered EEG Signal Right');
            xlim([0, max(app.right_time)]);
            ylim('auto');


        end

        function compareFFT(app)

            % Create a popup figure
            figure('Name', 'Compare Original and Filtered EEG FFT', 'Position', [100,100,1080,720]);

            % Find the left eeg FFt
            left_n = length(app.left_eeg);
            left_fs = left_n / max(app.file_time);
            left_frequencies = (0:left_n-1) * (left_fs/left_n);
            left_fft = abs(fft(app.left_eeg, left_n));

            % Find the right eeg FFt
            right_n = length(app.right_eeg);
            right_fs = right_n / max(app.file_time);
            right_frequencies = (0:right_n-1) * (right_fs/right_n);
            right_fft = abs(fft(app.right_eeg, right_n));

            % Plot the left signal fft
            subplot(4, 1, 1)
            plot(left_frequencies, left_fft, 'r');
            xlabel('Frequency');
            ylabel('|x|');
            title('EEG Signal Left FFT');
            xlim('auto');
            ylim('auto');

            % Plot the right signal fft
            subplot(4, 1, 2)
            plot(right_frequencies, right_fft, 'r');
            xlabel('Frequency');
            ylabel('|x|');
            title('EEG Signal Right FFT');
            xlim('auto');
            ylim('auto');

            % ============================================================

            % Get filtered left signal
            filtered_left = getFilteredSignal(app, app.left_eeg, false);

            % Get filtered right signal
            filtered_right = getFilteredSignal(app, app.right_eeg, false);

            % Get the filtered left and right FFT signals
            filtered_left_fft = abs(fft(filtered_left));
            filtered_right_fft = abs(fft(filtered_right));

            % Find frequencies for left and right FFT signals
            left_n = length(filtered_left);
            left_fs = left_n / max(app.file_time);
            left_frequencies = (0:left_n-1) * (left_fs/left_n);

            right_n = length(filtered_right);
            right_fs = right_n / max(app.file_time);
            right_frequencies = (0:right_n-1) * (right_fs/right_n);

            % Plot the left signal fft
            subplot(4, 1, 3)
            plot(left_frequencies, filtered_left_fft, 'r');
            xlabel('Frequency');
            ylabel('|x|');
            title('Filtered EEG Signal Left FFT');
            xlim('auto');
            ylim('auto');

            % Plot the left signal fft
            subplot(4, 1, 4)
            plot(right_frequencies, filtered_right_fft, 'r');
            xlabel('Frequency');
            ylabel('|x|');
            title('Filtered EEG Signal Right FFT');
            xlim('auto');
            ylim('auto');

        end

        function plotAllExtracted(app, name, filtered_signal, time, fs)

            % Create a popup figure
            figure('Name', name, 'Position', [100,100,1080,720]);

            % Gamma signal

            Fstop = 31.5;        % Stopband Frequency
            Fpass = 32;          % Passband Frequency
            Astop = 80;          % Stopband Attenuation (dB)
            Apass = 1;           % Passband Ripple (dB)
            match = 'stopband';  % Band to match exactly
            
            % Construct an FDESIGN object and call its CHEBY2 method.
            h  = fdesign.highpass(Fstop, Fpass, Astop, Apass, fs);
            Hd = design(h, 'cheby2', 'MatchExactly', match);
            
            gamma_signal = filter(Hd, filtered_signal);
            
            subplot(5, 1, 1)
            plot(time, gamma_signal, 'r')
            title("Gamma Signal")
            xlabel("Time (seconds)")
            ylabel("Voltage (uV)")
            xlim([0, 10])
            ylim('auto')
            
            % Beta signal
            
            Fstop1 = 12.5;        % First Stopband Frequency
            Fpass1 = 13;          % First Passband Frequency
            Fpass2 = 30;          % Second Passband Frequency
            Fstop2 = 30.5;        % Second Stopband Frequency
            Astop1 = 60;          % First Stopband Attenuation (dB)
            Apass  = 1;           % Passband Ripple (dB)
            Astop2 = 80;          % Second Stopband Attenuation (dB)
            match  = 'stopband';  % Band to match exactly
            
            % Construct an FDESIGN object and call its CHEBY2 method.
            h  = fdesign.bandpass(Fstop1, Fpass1, Fpass2, Fstop2, Astop1, Apass, Astop2, fs);
            Hd = design(h, 'cheby2', 'MatchExactly', match);
            
            beta_signal = filter(Hd, filtered_signal);
            
            subplot(5, 1, 2)
            plot(time, beta_signal, 'r')
            title("Beta Signal")
            xlabel("Time (seconds)")
            ylabel("Voltage (uV)")
            xlim([0, 10])
            ylim('auto')
            
            % Alpha signal
            
            Fstop1 = 7.5;         % First Stopband Frequency
            Fpass1 = 8;           % First Passband Frequency
            Fpass2 = 12;          % Second Passband Frequency
            Fstop2 = 12.5;        % Second Stopband Frequency
            Astop1 = 60;          % First Stopband Attenuation (dB)
            Apass  = 1;           % Passband Ripple (dB)
            Astop2 = 80;          % Second Stopband Attenuation (dB)
            match  = 'stopband';  % Band to match exactly
            
            % Construct an FDESIGN object and call its CHEBY2 method.
            h  = fdesign.bandpass(Fstop1, Fpass1, Fpass2, Fstop2, Astop1, Apass, Astop2, fs);
            Hd = design(h, 'cheby2', 'MatchExactly', match);
            
            alpha_signal = filter(Hd, filtered_signal);
            
            subplot(5, 1, 3)
            plot(time, alpha_signal, 'r')
            title("Alpha Signal")
            xlabel("Time (seconds)")
            ylabel("Voltage (uV)")
            xlim([0, 10])
            ylim('auto')
            
            % Theta signal
            
            Fstop1 = 3.5;         % First Stopband Frequency
            Fpass1 = 4;           % First Passband Frequency
            Fpass2 = 7;           % Second Passband Frequency
            Fstop2 = 7.1;         % Second Stopband Frequency
            Astop1 = 60;          % First Stopband Attenuation (dB)
            Apass  = 1;           % Passband Ripple (dB)
            Astop2 = 80;          % Second Stopband Attenuation (dB)
            match  = 'stopband';  % Band to match exactly
            
            % Construct an FDESIGN object and call its CHEBY2 method.
            h  = fdesign.bandpass(Fstop1, Fpass1, Fpass2, Fstop2, Astop1, Apass, Astop2, fs);
            Hd = design(h, 'cheby2', 'MatchExactly', match);
            
            theta_signal = filter(Hd, filtered_signal);
            
            subplot(5, 1, 4)
            plot(time, theta_signal, 'r')
            title("Theta Signal")
            xlabel("Time (seconds)")
            ylabel("Voltage (uV)")
            xlim([0, 10])
            ylim('auto')
            
            % Delta signal
            
            Fstop1 = 0.01;        % First Stopband Frequency
            Fpass1 = 0.2;         % First Passband Frequency
            Fpass2 = 4;           % Second Passband Frequency
            Fstop2 = 4.1;         % Second Stopband Frequency
            Astop1 = 60;          % First Stopband Attenuation (dB)
            Apass  = 1;           % Passband Ripple (dB)
            Astop2 = 80;          % Second Stopband Attenuation (dB)
            match  = 'stopband';  % Band to match exactly
            
            % Construct an FDESIGN object and call its CHEBY2 method.
            h  = fdesign.bandpass(Fstop1, Fpass1, Fpass2, Fstop2, Astop1, Apass, Astop2, fs);
            Hd = design(h, 'cheby2', 'MatchExactly', match);
            
            delta_signal = filter(Hd, filtered_signal);
            
            subplot(5, 1, 5)
            plot(time, delta_signal, 'r')
            title("Delta Signal")
            xlabel("Time (seconds)")
            ylabel("Voltage (uV)")
            xlim([0, 10])
            ylim('auto')

        end

        function compareExtracted(app)
            

            % For left signal
            filtered_signal = getFilteredSignal(app, app.left_eeg, false);
            time = app.left_time;
            n = length(app.left_eeg);
            fs = n / max(app.file_time);

            plotAllExtracted(app, 'Compare Left EEG Extracted Signals', filtered_signal, time, fs);

            % For right signal
            filtered_signal = getFilteredSignal(app, app.right_eeg, false);
            time = app.right_time;
            n = length(app.right_eeg);
            fs = n / max(app.file_time);

            plotAllExtracted(app, 'Compare Left EEG Extracted Signals', filtered_signal, time, fs);
            

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function openFile(app, event)

            % Open the file
            [file, path] = uigetfile;
            app.FileEditField.Value = strcat(path, file);
            eegFile = load(strcat(path, file));

            % Extract data from the file
            app.file_time = eegFile.b1(:,1);
            app.left_eeg = eegFile.b1(:,2);
            app.right_eeg = eegFile.b1(:,3);
            
            % Trim the signal and remove 0 values at the start and end
            first_nonzero_index = find(app.left_eeg ~= 0, 1, 'first');
            last_nonzero_index = find(app.left_eeg ~= 0, 1, 'last');
            app.left_eeg = app.left_eeg(first_nonzero_index:last_nonzero_index);
            
            % Find the sampling freq and signal time
            n = length(app.left_eeg);
            fs = n / max(app.file_time);
            app.left_time = (0:n-1) / fs;

            % Trim the signal and remove 0 values at the start and end
            first_nonzero_index = find(app.right_eeg ~= 0, 1, 'first');
            last_nonzero_index = find(app.right_eeg ~= 0, 1, 'last');
            app.right_eeg = app.right_eeg(first_nonzero_index:last_nonzero_index);
            
            % Find the sampling freq and signal time
            n = length(app.right_eeg);
            fs = n / max(app.file_time);
            app.right_time = (0:n-1) / fs;
                        
            % Plot the signal
            plotSignal(app);

        end

        % Button pushed function: PlotOriginalButton
        function plotSignalCall(app, event)
            plotSignal(app);
        end

        % Button pushed function: PlotFFTButton
        function plotFFTCall(app, event)
            plotFFT(app);
        end

        % Button pushed function: GammaButton
        function plotGammaCall(app, event)
            plotGamma(app);
        end

        % Button pushed function: AlphaButton
        function plotAlphaCall(app, event)
            plotAlpha(app);
        end

        % Button pushed function: ThetaButton
        function plotThetaCall(app, event)
            plotTheta(app);
        end

        % Button pushed function: DeltaButton
        function plotDeltaCall(app, event)
            plotDelta(app);
        end

        % Button pushed function: PlotFilteredButton
        function plot(app, event)
            plotFilteredSignal(app);
        end

        % Button pushed function: PlotFilteredFFTButton
        function plotFilteredFFTCall(app, event)
            plotFilteredFFT(app);
        end

        % Button pushed function: OriginalFilteredButton
        function compareOriginalCall(app, event)
            compareOriginal(app);
        end

        % Button pushed function: FFTOriginalFilteredButton
        function compareFFTCall(app, event)
            compareFFT(app);
        end

        % Button pushed function: ExtractedSignalsButton
        function compareExtractedCall(app, event)
            compareExtracted(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGEmbeddedWaveformExtractor10alphaUIFigure = uifigure('Visible', 'off');
            app.EEGEmbeddedWaveformExtractor10alphaUIFigure.Name = 'EEG Embedded Waveform Extractor | 1.0-alpha';
            app.MainGrid = uigridlayout(app.EEGEmbeddedWaveformExtractor10alphaUIFigure);
            app.ExtraPanel = uigridlayout(app.MainGrid);
            app.ControlPanel = uipanel(app.ExtraPanel);
            app.ControlPanel.Title = 'Control';
            app.GridLayout = uigridlayout(app.ControlPanel);
            app.PlotOriginalButton = uibutton(app.GridLayout, 'push');
            app.PlotOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalCall, true);
            app.PlotOriginalButton.Text = 'Plot Original';
            app.PlotFFTButton = uibutton(app.GridLayout, 'push');
            app.PlotFFTButton.ButtonPushedFcn = createCallbackFcn(app, @plotFFTCall, true);
            app.PlotFFTButton.Text = 'Plot FFT';
            app.GammaButton = uibutton(app.GridLayout, 'push');
            app.GammaButton.ButtonPushedFcn = createCallbackFcn(app, @plotGammaCall, true);
            app.GammaButton.Text = 'Gamma';
            app.AlphaButton = uibutton(app.GridLayout, 'push');
            app.AlphaButton.ButtonPushedFcn = createCallbackFcn(app, @plotAlphaCall, true);
            app.AlphaButton.Text = 'Alpha';
            app.ThetaButton = uibutton(app.GridLayout, 'push');
            app.ThetaButton.ButtonPushedFcn = createCallbackFcn(app, @plotThetaCall, true);
            app.ThetaButton.Text = 'Theta';
            app.DeltaButton = uibutton(app.GridLayout, 'push');
            app.DeltaButton.ButtonPushedFcn = createCallbackFcn(app, @plotDeltaCall, true);
            app.DeltaButton.Text = 'Delta';
            app.DomainLabel = uilabel(app.GridLayout);
            app.DomainLabel.Text = 'Domain';
            app.ExtractionLabel = uilabel(app.GridLayout);
            app.ExtractionLabel.Text = 'Extraction';
            app.PlotFilteredButton = uibutton(app.GridLayout, 'push');
            app.PlotFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @plot, true);
            app.PlotFilteredButton.Text = 'Plot Filtered';
            app.PlotFilteredFFTButton = uibutton(app.GridLayout, 'push');
            app.PlotFilteredFFTButton.ButtonPushedFcn = createCallbackFcn(app, @plotFilteredFFTCall, true);
            app.PlotFilteredFFTButton.Text = 'Plot Filtered FFT';
            app.CompareLabel = uilabel(app.GridLayout);
            app.CompareLabel.Text = 'Compare';
            app.OriginalFilteredButton = uibutton(app.GridLayout, 'push');
            app.OriginalFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @compareOriginalCall, true);
            app.OriginalFilteredButton.Text = 'Original & Filtered';
            app.FFTOriginalFilteredButton = uibutton(app.GridLayout, 'push');
            app.FFTOriginalFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @compareFFTCall, true);
            app.FFTOriginalFilteredButton.Text = 'FFT Original & Filtered';
            app.ExtractedSignalsButton = uibutton(app.GridLayout, 'push');
            app.ExtractedSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @compareExtractedCall, true);
            app.ExtractedSignalsButton.Text = 'Extracted Signals';
            app.StatsPanel = uipanel(app.ExtraPanel);
            app.StatsPanel.Title = 'Stats';
            app.GridLayout4 = uigridlayout(app.StatsPanel);
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'Type'; 'Value'};
            app.UITable.RowName = {};
            app.SignalPanel = uipanel(app.MainGrid);
            app.SignalPanel.Title = 'Signal';
            app.GridLayout2 = uigridlayout(app.SignalPanel);
            app.FilterAxes = uiaxes(app.GridLayout2);
            xlabel(app.FilterAxes, 'X')
            ylabel(app.FilterAxes, 'Y')
            zlabel(app.FilterAxes, 'Z')
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.SignalAxes = uiaxes(app.GridLayout3);
            xlabel(app.SignalAxes, 'X')
            ylabel(app.SignalAxes, 'Y')
            zlabel(app.SignalAxes, 'Z')
            colormap(app.SignalAxes, 'hot')
            app.SignalAxes_2 = uiaxes(app.GridLayout3);
            xlabel(app.SignalAxes_2, 'X')
            ylabel(app.SignalAxes_2, 'Y')
            zlabel(app.SignalAxes_2, 'Z')
            colormap(app.SignalAxes_2, 'hot')
            app.GridLayout5 = uigridlayout(app.MainGrid);
            app.OpenButton = uibutton(app.GridLayout5, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @openFile, true);
            app.OpenButton.Text = 'Open';
            app.FileEditFieldLabel = uilabel(app.GridLayout5);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.GridLayout5, 'text');
            app.FileEditField.Editable = 'off';
            % Show the figure after all components are created
            app.EEGEmbeddedWaveformExtractor10alphaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGEmbeddedWaveformExtractor10alphaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGEmbeddedWaveformExtractor10alphaUIFigure)
        end
    end
end